classdef CRESTA_v1_63 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        text22                          matlab.ui.control.Label
        edit47                          matlab.ui.control.EditField
        text23                          matlab.ui.control.Label
        edit45                          matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        VisualizationTab                matlab.ui.container.Tab
        uipanel13_2                     matlab.ui.container.Panel
        text19_4                        matlab.ui.control.Label
        cubeval                         matlab.ui.control.EditField
        zoomindir                       matlab.ui.control.EditField
        zoomoutdir                      matlab.ui.control.EditField
        pushbutton_zoom                 matlab.ui.control.Button
        text29_2                        matlab.ui.control.Label
        text30_2                        matlab.ui.control.Label
        zoomstar                        matlab.ui.control.EditField
        text32_2                        matlab.ui.control.Label
        uipanel11                       matlab.ui.container.Panel
        filterbutton_help_2             matlab.ui.control.Button
        filterbutton_help               matlab.ui.control.Button
        filtermrc_getdir_3              matlab.ui.control.Button
        FiltertypeButtonGroup           matlab.ui.container.ButtonGroup
        pushbutton11                    matlab.ui.control.Button
        text19_3                        matlab.ui.control.Label
        sigmavalue                      matlab.ui.control.EditField
        SNRval                          matlab.ui.control.EditField
        text19_2                        matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        edit42                          matlab.ui.control.EditField
        GaussianButton                  matlab.ui.control.RadioButton
        WienerButton                    matlab.ui.control.RadioButton
        edit58                          matlab.ui.control.EditField
        text36                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        edit40                          matlab.ui.control.EditField
        uipanel3                        matlab.ui.container.Panel
        OpenStackbrowserButton          matlab.ui.control.Button
        text3_3                         matlab.ui.control.Label
        alignout                        matlab.ui.control.EditField
        text3_2                         matlab.ui.control.Label
        aligninputnam                   matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        pushbutton3                     matlab.ui.control.Button
        edit7                           matlab.ui.control.EditField
        uipanel13                       matlab.ui.container.Panel
        ApplytranslationsCheckBox       matlab.ui.control.CheckBox
        edit51                          matlab.ui.control.EditField
        edit52                          matlab.ui.control.EditField
        pushbutton14                    matlab.ui.control.Button
        text29                          matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        edit54                          matlab.ui.control.EditField
        text32                          matlab.ui.control.Label
        CoordinateRepickerTab           matlab.ui.container.Tab
        text36_7                        matlab.ui.control.Label
        cmmdir                          matlab.ui.control.EditField
        CreatecoordsButton              matlab.ui.control.Button
        coordpicker                     matlab.ui.container.Panel
        notestatus                      matlab.ui.control.Label
        notecoordpick                   matlab.ui.control.EditField
        savenote                        matlab.ui.control.Button
        edit58_7                        matlab.ui.control.EditField
        text36_8                        matlab.ui.control.Label
        text17_3                        matlab.ui.control.Label
        filenamecoord                   matlab.ui.control.EditField
        PickcoordinatesButton_getdir_2  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        PickcoordinatesButton_getdir    matlab.ui.control.Button
        PickcoordinatesButton_rght_2    matlab.ui.control.Button
        edit58_6                        matlab.ui.control.EditField
        text36_6                        matlab.ui.control.Label
        PickcoordinatesButton_lft       matlab.ui.control.Button
        PickcoordinatesButton_rght      matlab.ui.control.Button
        text36_5                        matlab.ui.control.Label
        edit58_5                        matlab.ui.control.EditField
        edit58_4                        matlab.ui.control.EditField
        text36_4                        matlab.ui.control.Label
        edit58_3                        matlab.ui.control.EditField
        text36_3                        matlab.ui.control.Label
        PickcoordinatesButton           matlab.ui.control.Button
        edit58_2                        matlab.ui.control.EditField
        text36_2                        matlab.ui.control.Label
        text17_2                        matlab.ui.control.Label
        edit40_2                        matlab.ui.control.EditField
        CalculateAnglesTab              matlab.ui.container.Tab
        text17_6                        matlab.ui.control.Label
        text16_11                       matlab.ui.control.Label
        edit39_5                        matlab.ui.control.EditField
        pushbutton10_5                  matlab.ui.control.Button
        text16_10                       matlab.ui.control.Label
        pushbutton10_6                  matlab.ui.control.Button
        edit39_4                        matlab.ui.control.EditField
        TextArea_3                      matlab.ui.control.TextArea
        uipanel10_4                     matlab.ui.container.Panel
        text17_7                        matlab.ui.control.Label
        text16_13                       matlab.ui.control.Label
        edit39_7                        matlab.ui.control.EditField
        text16_12                       matlab.ui.control.Label
        edit39_6                        matlab.ui.control.EditField
        text17_4                        matlab.ui.control.Label
        text17_5                        matlab.ui.control.Label
        text16_9                        matlab.ui.control.Label
        edit39_3                        matlab.ui.control.EditField
        text16_8                        matlab.ui.control.Label
        edit39_2                        matlab.ui.control.EditField
        pushbutton10_4                  matlab.ui.control.Button
        DSignalSubTab                   matlab.ui.container.Tab
        uipanel1                        matlab.ui.container.Panel
        blurrate                        matlab.ui.control.EditField
        permute                         matlab.ui.control.CheckBox
        pushbutton1                     matlab.ui.control.Button
        normalized                      matlab.ui.control.CheckBox
        text13                          matlab.ui.control.Label
        edit37                          matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        edit1                           matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        text10                          matlab.ui.control.Label
        edit3                           matlab.ui.control.EditField
        edit2                           matlab.ui.control.EditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        sdShift                         matlab.ui.control.EditField
        sdRange                         matlab.ui.control.EditField
        text12_3                        matlab.ui.control.Label
        text12_2                        matlab.ui.control.Label
        checkbox_white                  matlab.ui.control.CheckBox
        checkbox_black                  matlab.ui.control.CheckBox
        checkbox1                       matlab.ui.control.CheckBox
        RandNoiseButton                 matlab.ui.control.RadioButton
        ShiftbySDButton                 matlab.ui.control.RadioButton
        uipanel9                        matlab.ui.container.Panel
        text14                          matlab.ui.control.Label
        pushbutton9                     matlab.ui.control.Button
        edit35                          matlab.ui.control.EditField
        uipanel8                        matlab.ui.container.Panel
        vertshift                       matlab.ui.control.EditField
        text8_2                         matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        edit30                          matlab.ui.control.EditField
        edit29                          matlab.ui.control.EditField
        edit28                          matlab.ui.control.EditField
        listbox2                        matlab.ui.control.ListBox
        pushbutton8                     matlab.ui.control.Button
        CrosscorrelationTab             matlab.ui.container.Tab
        uipanel10_3                     matlab.ui.container.Panel
        text16_6                        matlab.ui.control.Label
        cccmissingwedge                 matlab.ui.control.EditField
        text16_5                        matlab.ui.control.Label
        ccczoomrange                    matlab.ui.control.EditField
        text16_4                        matlab.ui.control.Label
        cccthresh                       matlab.ui.control.EditField
        text16_3                        matlab.ui.control.Label
        starfilecccdir                  matlab.ui.control.EditField
        cccvol1dir                      matlab.ui.control.EditField
        text15_3                        matlab.ui.control.Label
        pushbutton10_3                  matlab.ui.control.Button
        uipanel10_2                     matlab.ui.container.Panel
        text16_2                        matlab.ui.control.Label
        cccvol2                         matlab.ui.control.EditField
        cccvol1                         matlab.ui.control.EditField
        text15_2                        matlab.ui.control.Label
        pushbutton10_2                  matlab.ui.control.Button
        ChangeLogTab                    matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit23_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit24_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit25_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Demo_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Demo (see VARARGIN)
            
            % Choose default command line output for Demo
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            stackstar = get(hObject, 'String');
            data = guidata(hObject);
            guidata(hObject,data);
        end

        % Value changed function: listbox2
        function listbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            data = guidata(hObject);
            guidata(hObject,data);
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            data=guidata(hObject);
            dir=data.edit40;
            pixSize = data.edit47;
            defocus = data.edit42;
            listName = data.edit58.String;
            snrratio = str2double(app.SNRval.Value);
            sigval = str2double(app.sigmavalue.Value);
            if exist(listName)==0
                error('Star file not found')
            end
            filttypebutton = app.WienerButton.Value;
            if filttypebutton == 1
                deconv_filter_all_abs(dir.String, str2double(pixSize.String), str2double(defocus.String),snrratio)
            else
                gaussian_filter_all(dir.String,sigval)
            end
            newstar = strrep(listName, '.star', 'filt.star');
            if exist(newstar)==2
                system(['rm ',newstar]);
            end
            system(['sed s/.mrc/_filt.mrc/g ', listName, ' > ',newstar]);
            disp(strcat('Done. Creating new star file (NOT FOR USE IN RELION): ', newstar))
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            data=guidata(hObject);
            listName = data.edit54.String;
            newstar = strrep(listName, '.star', 'rot.star');
            dir = data.edit51.String;
            out = data.edit52.String;
            pxsz = str2double(data.edit47.String);
            boxsize = str2double(data.edit45.String);
            shifton = app.ApplytranslationsCheckBox.Value;
            if exist(newstar)==2
                system(['rm ',newstar]);
            end
            system(['sed s+',dir, '+',out,'+g ', listName, ' > ',newstar]);
            rotate_subtomos(listName, dir, out,pxsz,boxsize, shifton)
            disp(strcat('Done. Creating new star file: ', newstar))
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            data=guidata(hObject);
            mask = data.edit1.String;
            listName = data.edit37.String;
            dir = data.edit2.String;
            out = data.edit3.String;
            boxsize = str2double(data.edit45.String);
            pxsz = str2double(data.edit47.String);
            filter =data.checkbox1.Value;
            grow = str2double(app.blurrate.Value);
            normalizeit = app.normalized.Value;
            sdRange = str2double(app.sdRange.Value);
            sdShift = str2double(app.sdShift.Value);
            blackdust = app.checkbox_black.Value;
            whitedust = app.checkbox_white.Value;
            shiftfil = app.ShiftbySDButton.Value;
            randfilt = app.RandNoiseButton.Value;
            permutebg = app.permute.Value;
            cut_part_and_movefunc(mask, listName, dir, out, boxsize, pxsz, filter, grow, normalizeit,sdRange, sdShift,blackdust,whitedust,shiftfil,randfilt,permutebg)
            newstar = strrep(listName, '.star', 'reextract.star');
            if exist(newstar)==2
                system(['rm ',newstar]);
            end
            system(['sed s+',dir, '+',out,'+g ', listName, ' > ',newstar]);
            disp(strcat('Done. Creating new star file: ', newstar))
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            data=guidata(hObject);
            guidata(hObject,data);
            %disp(data.edit7)
            stackstar=data.edit7;
            staroutnm = app.alignout.Value;
            pxsz=str2double(data.edit47.String);
            %disp(stackstar.String)
            disp('Making Align File...')
            make_Align_after_relionfunc(stackstar.String,pxsz)
            disp('Complete')
            system(['mv Align.mat ',staroutnm]);
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data=guidata(hObject);
            boxsize = data.edit45;
            pxsz = data.edit47;
            rad = data.edit28;
            height = data.edit29;
            name = data.edit30;
            box = str2double(boxsize.String);
            listbox2 = data.listbox2;
            vertshift = app.vertshift.Value;
            
            if exist(name.String)==2
                    system(['rm ', name.String]);
            end
            if listbox2.Value == 1
                sphere = tom_spheremask(ones(box,box,box), str2double(rad.String),1,[round(box/2),round(box/2),round(box/2)]);
                tom_mrcwrite(sphere, 'name', name.String)
            elseif listbox2.Value == 2
                curve = 9649;
                offset = 0;
                cyl=tom_cylindermask(ones([box,box,box]),str2double(rad.String),1,[round(box/2),round(box/2)]);
                sph_top=(tom_spheremask(ones([box,box,box]),curve,1,[round(box/2),round(box/2),round(box/2)+offset-round(str2double(height)/2)-curve])-1)*-1;
                sph_bot=(tom_spheremask(ones([box,box,box]),curve,1,[round(box/2),round(box/2),round(box/2)+offset+round(str2double(height)/2)+curve])-1)*-1;
                mask_final=cyl.*sph_top.*sph_bot;
                test = mask_geometric(box,str2double(vertshift),str2double(rad.String),str2double(height.String),9649);
                %cyl = tom_cylindermask(, str2double(rad.String),1,[round(box/2),round(box/2)]);                
                tom_mrcwrite(test, 'name', name.String)
            end
            disp(strcat('Done. Writing mask: ', name.String))
%             if exist('chimera') == 1
%                 system(['alterheader ',name.String,' -del ',strcat(pxsz.String,',',pxsz.String,',',pxsz.String)]);
%             elseif exist('chimera') == 0
%                 disp('Cannot find Chimera. Attempting to source sbgrid')
%                 system(['source /programs/sbgrid.shrc && alterheader ',name.String,' -del ',strcat(pxsz.String,',',pxsz.String,',',pxsz.String)]);
%             end
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            data=guidata(hObject);
            mrc = data.edit35;
            vol = tom_mrcread(mrc.String);
            tom_volxyz(vol)
        end

        % Button pushed function: OpenStackbrowserButton
        function OpenStackbrowserButtonPushed(app, event)
            alignin = app.aligninputnam.Value;
            tom_av3_stackbrowser_2(alignin)
        end

        % Button pushed function: pushbutton_zoom
        function pushbutton_zoomButtonPushed(app, event)
            cubesize = str2double(app.cubeval.Value);
            listName = app.zoomstar.Value;
            dir = app.zoomindir.Value;
            out = app.zoomoutdir.Value;
            boxsize = str2double(app.edit45.Value);
            pxsz = str2double(app.edit47.Value);
            newstar = strrep(listName, '.star', 'zoom.star');
            if exist(newstar)==2
                system(['rm ',newstar]);
            end
            system(['sed s+',dir, '+',out,'+g ', listName, ' > ',newstar]);
            cut_and_zoom(cubesize, listName, dir, out, boxsize, pxsz)
            disp(strcat('Done. Creating new star file: ', newstar))
        end

        % Button pushed function: pushbutton10_2
        function pushbutton10_2ButtonPushed(app, event)
            cccvol1in = app.cccvol1.Value;
            cccvol2in = app.cccvol2.Value;
            
            invol1 = tom_mrcread(cccvol1in);
            invol1 = invol1.Value;
            invol2 = tom_mrcread(cccvol2in);
            invol2 = invol2.Value;
            tom_ccc(invol1(55:85,55:85,55:85),invol2(55:85,55:85,55:85),'norm')
        end

        % Button pushed function: pushbutton10_3
        function pushbutton10_3ButtonPushed(app, event)
            cccval = ccc_loop(app.starfilecccdir.Value, app.cccvol1dir.Value, str2double(app.cccthresh.Value),str2double(app.edit45.Value),str2double(app.ccczoomrange.Value),app.cccmissingwedge.Value)
            disp(strcat('Done. Creating new star file: ', strrep(app.starfilecccdir.Value,'.star','_out.star')))
        end

        % Button pushed function: PickcoordinatesButton
        function PickcoordinatesButtonPushed(app, event)
            ChimeraX_dir = app.edit58_3.Value;
            listName = app.edit58_2.Value;
            dir = app.edit40_2.Value;
            levels = app.edit58_4.Value;
            pxsz =str2double(app.edit47.Value);
            curindex = str2double(app.edit58_5.Value);
            cmmdir = app.edit58_7.Value;
            app.text17_3.Text = 'Please wait. Opening ChimeraX.';
            pause(0.01)
            [fnlength,fileNames,statstat] = coordinate_picker3(listName, dir, pxsz,ChimeraX_dir, levels,curindex,1, cmmdir);
            app.edit58_6.Value = fnlength;
            if statstat == 1
                app.text17_3.Text = 'Coords saved.';
            else
                app.text17_3.Text = 'No coords selected.';
                app.filenamecoord.Value = fileNames{curindex};
            end
        end

        % Button pushed function: PickcoordinatesButton_rght
        function PickcoordinatesButton_rghtPushed(app, event)
            app.edit58_5.Value = string(str2double(app.edit58_5.Value) + 1);
            pause(0.1)
            ChimeraX_dir = app.edit58_3.Value;
            listName = app.edit58_2.Value;
            dir = app.edit40_2.Value;
            levels = app.edit58_4.Value;
            pxsz =str2double(app.edit47.Value);
            curindex = str2double(app.edit58_5.Value);
            cmmdir = app.edit58_7.Value;
            app.text17_3.Text = 'Please wait. Opening ChimeraX.';
            pause(0.01)
            [fnlength,fileNames,statstat] = coordinate_picker3(listName, dir, pxsz,ChimeraX_dir, levels,curindex,1, cmmdir);
            app.edit58_6.Value = fnlength;
            if statstat == 1
                app.text17_3.Text = 'Coords saved.';
            else
                app.text17_3.Text = 'No coords selected.';
            end
            app.filenamecoord.Value = fileNames{curindex};
        end

        % Button pushed function: PickcoordinatesButton_lft
        function PickcoordinatesButton_lftPushed(app, event)
            app.edit58_5.Value = string(str2double(app.edit58_5.Value) - 1);
            pause(0.1)
            ChimeraX_dir = app.edit58_3.Value;
            listName = app.edit58_2.Value;
            dir = app.edit40_2.Value;
            levels = app.edit58_4.Value;
            pxsz =str2double(app.edit47.Value);
            curindex = str2double(app.edit58_5.Value);
            cmmdir = app.edit58_7.Value;
            app.text17_3.Text = 'Please wait. Opening ChimeraX.';
            pause(0.01)
            [fnlength,fileNames,statstat] = coordinate_picker3(listName, dir, pxsz,ChimeraX_dir, levels,curindex,1, cmmdir);
            app.edit58_6.Value = fnlength;
            if statstat == 1
                app.text17_3.Text = 'Coords saved.';
            else
                app.text17_3.Text = 'No coords selected.';
            end
            app.filenamecoord.Value = fileNames{curindex};
        end

        % Button pushed function: CreatecoordsButton
        function CreatecoordsButtonPushed(app, event)
            pxsz =app.edit47.Value;
            boxsize = app.edit45.Value;
            listName = app.edit58_2.Value;
            p = mfilename('fullpath');
            lastdot_pos = find(p == '/', 1, 'last');
            q = [p(1:lastdot_pos),'CRESTA_files/CMM_Cord_extract_jp2_JLEdit_5.sh'];
            addpath([app.cmmdir.Value,'cmm_files']);
            copyfile (listName,  [app.cmmdir.Value,'cmm_files']);
            %out = [q,' ',app.cmmdir.Value, 'cmm_files',' ', boxsize,' ', pxsz,' ',listName];
            system([q,' ',app.cmmdir.Value, 'cmm_files',' ', boxsize,' ', pxsz,' ',listName]);
        end

        % Button pushed function: PickcoordinatesButton_rght_2
        function PickcoordinatesButton_rght_2Pushed(app, event)
            uialert(app.figure1, 'Parent directory pointed to by star file.', 'Help','Icon', 'info')

        end

        % Button pushed function: PickcoordinatesButton_getdir
        function PickcoordinatesButton_getdirPushed(app, event)
            dir = pwd;
            app.edit58_2.Value = [dir,'/'];
            app.edit40_2.Value = [dir,'/'];
            app.edit58_7.Value = [dir,'/'];
            app.cmmdir.Value = [dir,'/'];
        end

        % Button pushed function: PickcoordinatesButton_getdir_2
        function PickcoordinatesButton_getdir_2Pushed(app, event)
            ChimeraX_dir = app.edit58_3.Value;
            listName = app.edit58_2.Value;
            dir = app.edit40_2.Value;
            levels = app.edit58_4.Value;
            pxsz =str2double(app.edit47.Value);
            curindex = str2double(app.edit58_5.Value);
            cmmdir = app.edit58_7.Value;
            [~,fileNames,~]=coordinate_picker3(listName, dir, pxsz,ChimeraX_dir, levels,curindex,0, cmmdir);
            app.filenamecoord.Value = fileNames{curindex};
        end

        % Button pushed function: savenote
        function savenotePushed(app, event)
            
            while isfile('coordpickernote.txt')==0
                fileID = fopen('coordpickernote.txt','w');
                fprintf(fileID,'%6s %12s\n','filename','note');
                fclose(fileID);
            end
            
            fileID = fopen('coordpickernote.txt','a');
            A = app.filenamecoord.Value;
            B = app.notecoordpick.Value;
            fprintf(fileID,'%6s %12s\n',A, B);
            fclose(fileID);
            app.notestatus.Text = 'Saved to /coordpickernote.txt';
        end

        % Button pushed function: pushbutton10_4
        function Calculate(app, event)
            star = app.edit39_5.Value;
            CoordDir = app.edit39_2.Value;
            Suffix = app.edit39_3.Value;
            Bin = app.edit39_6.Value;
            CMMDir = app.edit39_4.Value;
            lastslash_pos = find(star == '/', 1, 'last');
            if app.edit39_7.Value == '/';
                Out = [star(1:lastslash_pos),'Out'];
                app.edit39_7.Value = Out;
            else
                Out = app.edit39_7.Value;
            end
            p = mfilename('fullpath');
            lastdot_pos = find(p == '/', 1, 'last');
            q = [p(1:lastdot_pos),'CRESTA_files/Create_Centers2Data_file.sh'];
            Cresta_dir = [p(1:lastdot_pos),'CRESTA_files'];
            mkdir Out/temp
            system([q,' ', star,' ', CoordDir,' ', Out,' ', Suffix,' ', Cresta_dir, ' ', Bin, ' ', CMMDir]);
        end

        % Button pushed function: pushbutton10_6
        function pushbutton10_6ButtonPushed(app, event)
            app.edit39_4.Value = app.edit58_7.Value;
        end

        % Button pushed function: pushbutton10_5
        function pushbutton10_5ButtonPushed(app, event)
            app.edit39_5.Value = app.edit58_2.Value;
        end

        % Button pushed function: filtermrc_getdir_3
        function filtermrc_getdir_3ButtonPushed(app, event)
            dir = pwd;
            app.edit40.Value = [dir,'/'];
            app.edit58.Value = [dir,'/'];
        end

        % Button pushed function: filterbutton_help
        function filterbutton_helpButtonPushed(app, event)
            uialert(app.figure1, 'Directory that contains directories with the mrc files you want filtered.', 'Help','Icon', 'info')
        end

        % Button pushed function: filterbutton_help_2
        function filterbutton_help_2ButtonPushed(app, event)
            uialert(app.figure1, 'Path to star file. CrESTA will use this to create a new star file for visualizing filtered subtomograms elsewhere (e.g., the stackbrowser).', 'Help','Icon', 'info')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CRESTA 1.62';
            app.figure1.Tag = 'figure1';
            app.TabGroup = uitabgroup(app.figure1);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.uipanel13 = uipanel(app.VisualizationTab);
            app.uipanel13.Title = 'Rotate Subtomograms';
            app.uipanel13.Tag = 'uipanel13';
            app.text32 = uilabel(app.uipanel13);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Star file path';
            app.edit54 = uieditfield(app.uipanel13, 'text');
            app.edit54.Tag = 'edit54';
            app.edit54.Value = 'test.star';
            app.text30 = uilabel(app.uipanel13);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Output directory';
            app.text29 = uilabel(app.uipanel13);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Subtomogram directory';
            app.pushbutton14 = uibutton(app.uipanel13, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Text = 'Rotate';
            app.edit52 = uieditfield(app.uipanel13, 'text');
            app.edit52.Tag = 'edit52';
            app.edit52.Value = 'Extract/extract_test/201810XX_MPI_rot/';
            app.edit51 = uieditfield(app.uipanel13, 'text');
            app.edit51.Tag = 'edit51';
            app.edit51.Value = 'Extract/extract_test/201810XX_MPI/';
            app.ApplytranslationsCheckBox = uicheckbox(app.uipanel13);
            app.ApplytranslationsCheckBox.Tag = 'ApplytranslationsCheckBox';
            app.ApplytranslationsCheckBox.Text = 'Apply translations';
            app.ApplytranslationsCheckBox.Value = true;
            app.uipanel3 = uipanel(app.VisualizationTab);
            app.uipanel3.Title = 'Stackbrowser';
            app.uipanel3.Tag = 'uipanel3';
            app.edit7 = uieditfield(app.uipanel3, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Tag = 'edit7';
            app.edit7.Value = 'test.star';
            app.pushbutton3 = uibutton(app.uipanel3, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Make Align File';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Star file path';
            app.aligninputnam = uieditfield(app.uipanel3, 'text');
            app.aligninputnam.Tag = 'edit7';
            app.aligninputnam.Value = 'Align.mat';
            app.text3_2 = uilabel(app.uipanel3);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Align file for stackbrowser';
            app.alignout = uieditfield(app.uipanel3, 'text');
            app.alignout.Tag = 'edit7';
            app.alignout.Value = 'Align.mat';
            app.text3_3 = uilabel(app.uipanel3);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = 'Align file name (output)';
            app.OpenStackbrowserButton = uibutton(app.uipanel3, 'push');
            app.OpenStackbrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpenStackbrowserButtonPushed, true);
            app.OpenStackbrowserButton.Text = 'Open Stackbrowser';
            app.uipanel11 = uipanel(app.VisualizationTab);
            app.uipanel11.Title = 'Filter mrc';
            app.uipanel11.Tag = 'uipanel11';
            app.edit40 = uieditfield(app.uipanel11, 'text');
            app.edit40.Tag = 'edit40';
            app.edit40.Value = 'Extract/extract_test/201810XX_MPI/';
            app.text17 = uilabel(app.uipanel11);
            app.text17.Tag = 'text17';
            app.text17.Text = 'mrc directory';
            app.text36 = uilabel(app.uipanel11);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Star file path';
            app.edit58 = uieditfield(app.uipanel11, 'text');
            app.edit58.Tag = 'edit58';
            app.edit58.Value = 'test.star';
            app.FiltertypeButtonGroup = uibuttongroup(app.uipanel11);
            app.FiltertypeButtonGroup.Title = 'Filter type';
            app.WienerButton = uiradiobutton(app.FiltertypeButtonGroup);
            app.WienerButton.Text = 'Wiener';
            app.WienerButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FiltertypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.edit42 = uieditfield(app.FiltertypeButtonGroup, 'text');
            app.edit42.Tag = 'edit42';
            app.edit42.Value = '3';
            app.text19 = uilabel(app.FiltertypeButtonGroup);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Avg defocus (um)';
            app.text19_2 = uilabel(app.FiltertypeButtonGroup);
            app.text19_2.Tag = 'text19';
            app.text19_2.Text = 'SNR falloff';
            app.SNRval = uieditfield(app.FiltertypeButtonGroup, 'text');
            app.SNRval.Tag = 'edit42';
            app.SNRval.Value = '1.2';
            app.sigmavalue = uieditfield(app.FiltertypeButtonGroup, 'text');
            app.sigmavalue.Tag = 'edit42';
            app.sigmavalue.Value = '5';
            app.text19_3 = uilabel(app.FiltertypeButtonGroup);
            app.text19_3.Tag = 'text19';
            app.text19_3.Text = 'sigma';
            app.pushbutton11 = uibutton(app.FiltertypeButtonGroup, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'Filter Volumes';
            app.filtermrc_getdir_3 = uibutton(app.uipanel11, 'push');
            app.filtermrc_getdir_3.ButtonPushedFcn = createCallbackFcn(app, @filtermrc_getdir_3ButtonPushed, true);
            app.filtermrc_getdir_3.Text = 'get dir';
            app.filterbutton_help = uibutton(app.uipanel11, 'push');
            app.filterbutton_help.ButtonPushedFcn = createCallbackFcn(app, @filterbutton_helpButtonPushed, true);
            app.filterbutton_help.Text = '?';
            app.filterbutton_help_2 = uibutton(app.uipanel11, 'push');
            app.filterbutton_help_2.ButtonPushedFcn = createCallbackFcn(app, @filterbutton_help_2ButtonPushed, true);
            app.filterbutton_help_2.Text = '?';
            app.uipanel13_2 = uipanel(app.VisualizationTab);
            app.uipanel13_2.Title = 'Box Zoom';
            app.uipanel13_2.Tag = 'uipanel13';
            app.text32_2 = uilabel(app.uipanel13_2);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'Star file path';
            app.zoomstar = uieditfield(app.uipanel13_2, 'text');
            app.zoomstar.Tag = 'zoomstar';
            app.zoomstar.Value = 'test.star';
            app.text30_2 = uilabel(app.uipanel13_2);
            app.text30_2.Tag = 'text30';
            app.text30_2.Text = 'Output directory';
            app.text29_2 = uilabel(app.uipanel13_2);
            app.text29_2.Tag = 'text29';
            app.text29_2.Text = 'Subtomogram directory';
            app.pushbutton_zoom = uibutton(app.uipanel13_2, 'push');
            app.pushbutton_zoom.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_zoomButtonPushed, true);
            app.pushbutton_zoom.Tag = 'pushbutton_zoom';
            app.pushbutton_zoom.Text = 'Zoom and Cut';
            app.zoomoutdir = uieditfield(app.uipanel13_2, 'text');
            app.zoomoutdir.Tag = 'zoomoutdir';
            app.zoomoutdir.Value = 'Extract/extract_test/201810XX_MPI_zoom/';
            app.zoomindir = uieditfield(app.uipanel13_2, 'text');
            app.zoomindir.Tag = 'zoomindir';
            app.zoomindir.Value = 'Extract/extract_test/201810XX_MPI/';
            app.cubeval = uieditfield(app.uipanel13_2, 'text');
            app.cubeval.Tag = 'cubeval';
            app.cubeval.Value = '90';
            app.text19_4 = uilabel(app.uipanel13_2);
            app.text19_4.Tag = 'text19';
            app.text19_4.Text = 'zoom box size (px)';
            app.CoordinateRepickerTab = uitab(app.TabGroup);
            app.CoordinateRepickerTab.Title = 'Coordinate Re-picker';
            app.coordpicker = uipanel(app.CoordinateRepickerTab);
            app.coordpicker.Title = 'Coord re-picker';
            app.coordpicker.Tag = 'uipanel11';
            app.edit40_2 = uieditfield(app.coordpicker, 'text');
            app.edit40_2.Tag = 'edit40_2';
            app.edit40_2.Value = 'Test_Data/';
            app.text17_2 = uilabel(app.coordpicker);
            app.text17_2.Tag = 'text17_2';
            app.text17_2.Text = 'mrc directory';
            app.text36_2 = uilabel(app.coordpicker);
            app.text36_2.Tag = 'text36_2';
            app.text36_2.Text = 'Star file path';
            app.edit58_2 = uieditfield(app.coordpicker, 'text');
            app.edit58_2.Tag = 'edit58_2';
            app.edit58_2.Value = 'Test_Data/test_2018filt.star';
            app.PickcoordinatesButton = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButtonPushed, true);
            app.PickcoordinatesButton.Text = 'Pick coordinates';
            app.text36_3 = uilabel(app.coordpicker);
            app.text36_3.Tag = 'text36';
            app.text36_3.Text = 'ChimeraX path (default is SBgrid location)';
            app.edit58_3 = uieditfield(app.coordpicker, 'text');
            app.edit58_3.Tag = 'edit58_3';
            app.edit58_3.Value = '/opt/sbgrid/i386-mac/chimerax/1.3/ChimeraX.app/Contents/bin';
            app.text36_4 = uilabel(app.coordpicker);
            app.text36_4.Tag = 'text36';
            app.text36_4.Text = 'Surface level';
            app.edit58_4 = uieditfield(app.coordpicker, 'text');
            app.edit58_4.Tag = 'edit58_4';
            app.edit58_4.Value = '0.5';
            app.edit58_5 = uieditfield(app.coordpicker, 'text');
            app.edit58_5.Tag = 'edit58_5';
            app.edit58_5.Value = '1';
            app.text36_5 = uilabel(app.coordpicker);
            app.text36_5.Tag = 'text36';
            app.text36_5.Text = 'Index';
            app.PickcoordinatesButton_rght = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton_rght.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButton_rghtPushed, true);
            app.PickcoordinatesButton_rght.Text = '=>';
            app.PickcoordinatesButton_lft = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton_lft.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButton_lftPushed, true);
            app.PickcoordinatesButton_lft.Text = '<=';
            app.text36_6 = uilabel(app.coordpicker);
            app.text36_6.Tag = 'text36';
            app.text36_6.Text = 'of';
            app.edit58_6 = uieditfield(app.coordpicker, 'text');
            app.edit58_6.Tag = 'edit58_6';
            app.edit58_6.Value = '0';
            app.PickcoordinatesButton_rght_2 = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton_rght_2.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButton_rght_2Pushed, true);
            app.PickcoordinatesButton_rght_2.Text = '?';
            app.PickcoordinatesButton_getdir = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton_getdir.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButton_getdirPushed, true);
            app.PickcoordinatesButton_getdir.Text = 'get dir';
            app.TextArea = uitextarea(app.coordpicker);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click "Pick coordinates" to Right click to select coordinates. Navigate to Markers menu and select "Move" if you need to adjust the location of the coordinate. '; ''; 'When finished, save coord file using command "save coord.cmm". Then exit Chimera. Press the right arrow to progres; the next subtomogram will automatically open.'; ''; 'When you are done with all of your pick, press the "Create .coords" button to create new coordinate files.'};
            app.PickcoordinatesButton_getdir_2 = uibutton(app.coordpicker, 'push');
            app.PickcoordinatesButton_getdir_2.ButtonPushedFcn = createCallbackFcn(app, @PickcoordinatesButton_getdir_2Pushed, true);
            app.PickcoordinatesButton_getdir_2.Text = 'get file name';
            app.filenamecoord = uieditfield(app.coordpicker, 'text');
            app.text17_3 = uilabel(app.coordpicker);
            app.text17_3.Tag = 'text17_2';
            app.text17_3.Text = 'Click above to begin.';
            app.text36_8 = uilabel(app.coordpicker);
            app.text36_8.Tag = 'text36';
            app.text36_8.Text = 'ChimeraX output directory';
            app.edit58_7 = uieditfield(app.coordpicker, 'text');
            app.edit58_7.Tag = 'edit58_3';
            app.edit58_7.Value = '/';
            app.savenote = uibutton(app.coordpicker, 'push');
            app.savenote.ButtonPushedFcn = createCallbackFcn(app, @savenotePushed, true);
            app.savenote.Text = 'Add note';
            app.notecoordpick = uieditfield(app.coordpicker, 'text');
            app.notestatus = uilabel(app.coordpicker);
            app.notestatus.Tag = 'text17_2';
            app.notestatus.Text = '';
            app.CreatecoordsButton = uibutton(app.CoordinateRepickerTab, 'push');
            app.CreatecoordsButton.ButtonPushedFcn = createCallbackFcn(app, @CreatecoordsButtonPushed, true);
            app.CreatecoordsButton.Text = 'Create .coords';
            app.cmmdir = uieditfield(app.CoordinateRepickerTab, 'text');
            app.text36_7 = uilabel(app.CoordinateRepickerTab);
            app.text36_7.Tag = 'text36';
            app.text36_7.Text = 'ChimeraX coordinate directory';
            app.CalculateAnglesTab = uitab(app.TabGroup);
            app.CalculateAnglesTab.Title = 'Calculate Angles';
            app.uipanel10_4 = uipanel(app.CalculateAnglesTab);
            app.uipanel10_4.Title = '      Calculate Rotations';
            app.uipanel10_4.Tag = 'uipanel10';
            app.pushbutton10_4 = uibutton(app.uipanel10_4, 'push');
            app.pushbutton10_4.ButtonPushedFcn = createCallbackFcn(app, @Calculate, true);
            app.pushbutton10_4.Tag = 'pushbutton10';
            app.pushbutton10_4.Text = 'Calculate Angles';
            app.edit39_2 = uieditfield(app.uipanel10_4, 'text');
            app.edit39_2.Tag = 'edit39';
            app.edit39_2.Value = '/';
            app.text16_8 = uilabel(app.uipanel10_4);
            app.text16_8.Tag = 'text16';
            app.text16_8.Text = 'Coordinate Files Folder Path';
            app.edit39_3 = uieditfield(app.uipanel10_4, 'text');
            app.edit39_3.Tag = 'edit39';
            app.edit39_3.Value = 'C';
            app.text16_9 = uilabel(app.uipanel10_4);
            app.text16_9.Tag = 'text16';
            app.text16_9.Text = 'Second .coord file suffix';
            app.text17_5 = uilabel(app.uipanel10_4);
            app.text17_5.Tag = 'text17_2';
            app.text17_5.Text = 'e.g. for Example.coordC, input would be C';
            app.text17_4 = uilabel(app.uipanel10_4);
            app.text17_4.Tag = 'text17_2';
            app.text17_4.Text = 'Note: must contain all of the .coord files';
            app.edit39_6 = uieditfield(app.uipanel10_4, 'text');
            app.edit39_6.Tag = 'edit39';
            app.edit39_6.Value = '4';
            app.text16_12 = uilabel(app.uipanel10_4);
            app.text16_12.Tag = 'text16';
            app.text16_12.Text = 'Second .coord file Binn';
            app.edit39_7 = uieditfield(app.uipanel10_4, 'text');
            app.edit39_7.Tag = 'edit39';
            app.edit39_7.Value = '/';
            app.text16_13 = uilabel(app.uipanel10_4);
            app.text16_13.Tag = 'text16';
            app.text16_13.Text = 'Output Path';
            app.text17_7 = uilabel(app.uipanel10_4);
            app.text17_7.Tag = 'text17_2';
            app.text17_7.Text = 'May be left blank if OUTPUT directory is desired to be in current working directory.  If so, then it will be in "Out" folder';
            app.TextArea_3 = uitextarea(app.CalculateAnglesTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'In this tab, use the Calculate Rotations option to generate euler angles from a second input coordinate file.  This file must match the original coordinates file line for line.'};
            app.edit39_4 = uieditfield(app.CalculateAnglesTab, 'text');
            app.edit39_4.Tag = 'edit39';
            app.edit39_4.Value = '/';
            app.pushbutton10_6 = uibutton(app.CalculateAnglesTab, 'push');
            app.pushbutton10_6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_6ButtonPushed, true);
            app.pushbutton10_6.Tag = 'pushbutton10';
            app.pushbutton10_6.Text = 'Get path from Coord Picker';
            app.text16_10 = uilabel(app.CalculateAnglesTab);
            app.text16_10.Tag = 'text16';
            app.text16_10.Text = 'Cmm file path';
            app.pushbutton10_5 = uibutton(app.CalculateAnglesTab, 'push');
            app.pushbutton10_5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_5ButtonPushed, true);
            app.pushbutton10_5.Tag = 'pushbutton10';
            app.pushbutton10_5.Text = 'Get path from Coord Picker';
            app.edit39_5 = uieditfield(app.CalculateAnglesTab, 'text');
            app.edit39_5.Tag = 'edit39';
            app.edit39_5.Value = '/';
            app.text16_11 = uilabel(app.CalculateAnglesTab);
            app.text16_11.Tag = 'text16';
            app.text16_11.Text = 'Star file input';
            app.text17_6 = uilabel(app.CalculateAnglesTab);
            app.text17_6.Tag = 'text17_2';
            app.text17_6.Text = 'Input might be particles.star';
            app.DSignalSubTab = uitab(app.TabGroup);
            app.DSignalSubTab.Title = '3D Signal Sub.';
            app.uipanel8 = uipanel(app.DSignalSubTab);
            app.uipanel8.Title = 'Create Mask';
            app.uipanel8.Tag = 'uipanel8';
            app.pushbutton8 = uibutton(app.uipanel8, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = 'Make Mask';
            app.listbox2 = uilistbox(app.uipanel8);
            app.listbox2.Items = {'Sphere', 'Cylinder'};
            app.listbox2.ValueChangedFcn = createCallbackFcn(app, @listbox2_Callback, true);
            app.listbox2.Tag = 'listbox2';
            app.listbox2.Value = 'Cylinder';
            app.edit28 = uieditfield(app.uipanel8, 'text');
            app.edit28.Tag = 'edit28';
            app.edit28.Value = '35';
            app.edit29 = uieditfield(app.uipanel8, 'text');
            app.edit29.Tag = 'edit29';
            app.edit29.Value = '65';
            app.edit30 = uieditfield(app.uipanel8, 'text');
            app.edit30.Tag = 'edit30';
            app.edit30.Value = 'mask.mrc';
            app.text4 = uilabel(app.uipanel8);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Save mask as';
            app.text7 = uilabel(app.uipanel8);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Mask radius (px)';
            app.text8 = uilabel(app.uipanel8);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Cyl height (px)';
            app.text8_2 = uilabel(app.uipanel8);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Vertical Translation (px)';
            app.vertshift = uieditfield(app.uipanel8, 'text');
            app.vertshift.Tag = 'vertshift';
            app.vertshift.Value = '-8';
            app.uipanel9 = uipanel(app.DSignalSubTab);
            app.uipanel9.Title = 'Display mrc in tom xyz';
            app.uipanel9.Tag = 'uipanel9';
            app.edit35 = uieditfield(app.uipanel9, 'text');
            app.edit35.Tag = 'edit35';
            app.edit35.Value = 'mask.mrc';
            app.pushbutton9 = uibutton(app.uipanel9, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'Display Volume';
            app.text14 = uilabel(app.uipanel9);
            app.text14.Tag = 'text14';
            app.text14.Text = 'mrc path';
            app.uipanel1 = uipanel(app.DSignalSubTab);
            app.uipanel1.Title = '3D signal subtraction';
            app.uipanel1.Tag = 'uipanel1';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.ShiftbySDButton = uiradiobutton(app.ButtonGroup);
            app.ShiftbySDButton.Text = 'Shift by SD';
            app.ShiftbySDButton.Value = true;
            app.RandNoiseButton = uiradiobutton(app.ButtonGroup);
            app.RandNoiseButton.Text = 'Rand Noise';
            app.checkbox1 = uicheckbox(app.ButtonGroup);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Filter background';
            app.checkbox1.Value = true;
            app.checkbox_black = uicheckbox(app.ButtonGroup);
            app.checkbox_black.Tag = 'checkbox1';
            app.checkbox_black.Text = 'Black dust removal';
            app.checkbox_black.Value = true;
            app.checkbox_white = uicheckbox(app.ButtonGroup);
            app.checkbox_white.Tag = 'checkbox1';
            app.checkbox_white.Text = 'White dust removal';
            app.checkbox_white.Value = true;
            app.text12_2 = uilabel(app.ButtonGroup);
            app.text12_2.Tag = 'text12';
            app.text12_2.Text = 'S.D thresh to filter';
            app.text12_3 = uilabel(app.ButtonGroup);
            app.text12_3.Tag = 'text12';
            app.text12_3.Text = 'S.D shift';
            app.sdRange = uieditfield(app.ButtonGroup, 'text');
            app.sdRange.Tag = 'edit36';
            app.sdRange.Value = '2.0';
            app.sdShift = uieditfield(app.ButtonGroup, 'text');
            app.sdShift.Tag = 'edit36';
            app.sdShift.Value = '1.0';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = 'Extract/extract_test/201810XX_MPI/';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = 'Extract/extract_test/201810XX_MPI_reextract/';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Subtomogram directory';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Output directory';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Mask blur rate';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'mask.mrc';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Mask path';
            app.edit37 = uieditfield(app.uipanel1, 'text');
            app.edit37.Tag = 'edit37';
            app.edit37.Value = 'test.star';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Star file path';
            app.normalized = uicheckbox(app.uipanel1);
            app.normalized.Tag = 'checkbox1';
            app.normalized.Text = 'Normalize after filtering';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Re-extract';
            app.permute = uicheckbox(app.uipanel1);
            app.permute.Tag = 'checkbox1';
            app.permute.Text = 'Permute Background';
            app.permute.Value = true;
            app.blurrate = uieditfield(app.uipanel1, 'text');
            app.blurrate.Tag = 'edit36';
            app.blurrate.Value = '1.1';
            app.CrosscorrelationTab = uitab(app.TabGroup);
            app.CrosscorrelationTab.Title = 'Cross-correlation';
            app.uipanel10_2 = uipanel(app.CrosscorrelationTab);
            app.uipanel10_2.Title = 'Calculate CCC - Two Volumes';
            app.uipanel10_2.Tag = 'uipanel10';
            app.pushbutton10_2 = uibutton(app.uipanel10_2, 'push');
            app.pushbutton10_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_2ButtonPushed, true);
            app.pushbutton10_2.Tag = 'pushbutton10';
            app.pushbutton10_2.Text = 'Calculate CCC';
            app.text15_2 = uilabel(app.uipanel10_2);
            app.text15_2.Tag = 'text15';
            app.text15_2.Text = 'Volume 1';
            app.cccvol1 = uieditfield(app.uipanel10_2, 'text');
            app.cccvol1.Tag = 'edit38';
            app.cccvol1.Value = '.mrc';
            app.cccvol2 = uieditfield(app.uipanel10_2, 'text');
            app.cccvol2.Tag = 'edit39';
            app.cccvol2.Value = '.mrc';
            app.text16_2 = uilabel(app.uipanel10_2);
            app.text16_2.Tag = 'text16';
            app.text16_2.Text = 'Volume 2';
            app.uipanel10_3 = uipanel(app.CrosscorrelationTab);
            app.uipanel10_3.Title = 'Volume & Star File';
            app.uipanel10_3.Tag = 'uipanel10';
            app.pushbutton10_3 = uibutton(app.uipanel10_3, 'push');
            app.pushbutton10_3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_3ButtonPushed, true);
            app.pushbutton10_3.Tag = 'pushbutton10';
            app.pushbutton10_3.Text = 'Filter by CCC';
            app.text15_3 = uilabel(app.uipanel10_3);
            app.text15_3.Tag = 'text15';
            app.text15_3.Text = 'Volume 1';
            app.cccvol1dir = uieditfield(app.uipanel10_3, 'text');
            app.cccvol1dir.Tag = 'edit38';
            app.cccvol1dir.Value = '.mrc';
            app.starfilecccdir = uieditfield(app.uipanel10_3, 'text');
            app.starfilecccdir.Tag = 'edit39';
            app.starfilecccdir.Value = '.star';
            app.text16_3 = uilabel(app.uipanel10_3);
            app.text16_3.Tag = 'text16';
            app.text16_3.Text = 'Star file';
            app.cccthresh = uieditfield(app.uipanel10_3, 'text');
            app.cccthresh.Tag = 'edit39';
            app.cccthresh.Value = '0';
            app.text16_4 = uilabel(app.uipanel10_3);
            app.text16_4.Tag = 'text16';
            app.text16_4.Text = 'CCC Threshold ';
            app.ccczoomrange = uieditfield(app.uipanel10_3, 'text');
            app.ccczoomrange.Tag = 'ccczoomrange';
            app.ccczoomrange.Value = '40';
            app.text16_5 = uilabel(app.uipanel10_3);
            app.text16_5.Tag = 'text16';
            app.text16_5.Text = 'Zoom range (px)';
            app.cccmissingwedge = uieditfield(app.uipanel10_3, 'text');
            app.cccmissingwedge.Tag = 'cccmissingwedge';
            app.cccmissingwedge.Value = '.mrc';
            app.text16_6 = uilabel(app.uipanel10_3);
            app.text16_6.Tag = 'text16';
            app.text16_6.Text = 'Missing wedge file';
            app.ChangeLogTab = uitab(app.TabGroup);
            app.ChangeLogTab.Title = 'Change Log';
            app.TextArea_2 = uitextarea(app.ChangeLogTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Version 1.63'; '-added ''get dir'' button to filter function on Visualization tab'; '-added help buttons (''?'') to give more information about directories for filter function on Visualization tab'; ''; 'Version 1.61'; '-calculate angles tab added'; ''; 'Version 1.6'; '-create coords button in coordinate picker is compatible with more input star files'; '-calls coordinate_picker3 which has typo fix'; ''; 'Version 1.54'; '-minor bug fixes in coordinate picker'; '-"get dir" button in coordinate picker automatically adds ''/'' to end of path'; ''; 'Version 1.53'; '1. Small bug fixes in coordinate picker.'; ''; 'Version 1.51'; '1. Current file auto-updates after closing ChimeraX in coordinate picker.'; '2. Add tool to save notes about subtomogtrams in coordinate picker.'; ''; 'Version 1.5'; '1, Additional instruction added to Coordinate Picker.'; '2. Status update of ChimeraX is provided after opening a file to prevent multiple clicks.'; '3. Added option for output directory for the cmm coordinate files created by ChimeraX'; '4. Added input option for the cmm coordinate files for the "Creates .coords" feature which makes .coordsshift files compatible with RELION3.'; '5. Created change log tab.'; ''; 'Version 1.45'; '1. "get dir" button added. This allows you to update the "mrc directory" and "Star file path" fields to the current directory.'; '2. Changed directory conventions for "mrc directory" and "Star file path." You now need to enter the complete directories into this field (the "get dir" button follows this convention).'; '3.  Added "get file name" button which prints the current file name based on the index. The "mrc directory" combined with the file name is the path to the file that is opened in Chimera. You can check to make sure your path makes sense using this feature.'; '4. The default click in ChimeraX chooses the surface instead of the plane.'};
            app.edit45 = uieditfield(app.figure1, 'text');
            app.edit45.Tag = 'edit45';
            app.edit45.Value = '256';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Box size (px)';
            app.edit47 = uieditfield(app.figure1, 'text');
            app.edit47.Tag = 'edit47';
            app.edit47.Value = '2.62';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Px size (A)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CRESTA_v1_63(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Demo_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end